classdef selectRuns < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        dataTable                    matlab.ui.control.Table
        SwitchAllHighlightedEntriesButton  matlab.ui.control.Button
        DataSelectionToolLabel       matlab.ui.control.Label
        ThistoolwillhelpyouLabel     matlab.ui.control.Label
        Step1Label                   matlab.ui.control.Label
        Step1Label_2                 matlab.ui.control.Label
        generateButton               matlab.ui.control.Button
        VariedVarsCSVspacesokLabel   matlab.ui.control.Label
        VariedTextArea               matlab.ui.control.TextArea
        Step1Label_3                 matlab.ui.control.Label
        HeldVarsEachCSVspacesokTextAreaLabel  matlab.ui.control.Label
        HeldTextArea                 matlab.ui.control.TextArea
        HeldVarsAllCSVspacesokLabel  matlab.ui.control.Label
        HeldAllTextArea              matlab.ui.control.TextArea
        Step4WhatwouldyoulikeyouratomDatastobecalledEditFieldLabel  matlab.ui.control.Label
        nameEditField                matlab.ui.control.EditField
        VariablevariedacrosseachselectedrunLabel  matlab.ui.control.Label
        VariablesheldconstantthroughouteachrunLabel  matlab.ui.control.Label
        VariablesheldconstantacrossallselectedrunsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        table_selected % Selected cells in the dataTable (used to make switching multiple possible)
        InputData % The input RunDataLibrary
    end
    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, InputData)
            if(nargin<2)
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%');
                disp('%%%%%%%%You must have the RunDataLibrary object as an input!!%%%%%%%%');
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%');
            end
            
            %collect the information from the RunDataLibrary (RunIDs,
            %Folders, dates etc..)
            %include boolean is choice of the user
            RunIDs = cellfun(@(x) x.RunID, InputData.RunDatas,'UniformOutput',false);
            Folders = cellfun(@(x) x.RunFolder, InputData.RunDatas,'UniformOutput',false);
            dates = cellfun(@(x) datetime(x.Year,x.Month,x.Day),InputData.RunDatas,'UniformOutput',false);
            include_bool = false(size(Folders));
            
            app.dataTable.Data = table(Folders,dates,RunIDs,include_bool);
            app.InputData = InputData;
        end

        % Button pushed function: SwitchAllHighlightedEntriesButton
        function SwitchAllHighlightedEntriesButtonPushed(app, event)
            curr_bool = app.dataTable.Data{app.table_selected(1,1),4};
            new_bool = not(curr_bool);
            
            for ii = 1:size(app.table_selected,1)
                app.dataTable.Data{app.table_selected(ii,1),4} = new_bool;
            end
        end

        % Cell selection callback: dataTable
        function dataTableCellSelection(app, event)
            indices = event.Indices;
            
            app.table_selected = indices;
        end

        % Button pushed function: generateButton
        function generateButtonPushed(app, event)
            tab = app.dataTable.Data;
            incl_RunIDs = tab.RunIDs(tab.include_bool);
            
            condition = {'RunID',incl_RunIDs};
            
            %note that these are used for plot headers, labels etc
            varied_var = split(strrep(app.VariedTextArea.Value,' ',''),',');
            heldvars_each = split(strrep(app.HeldTextArea.Value,' ',''),',');
            heldvars_all = split(strrep(app.HeldAllTextArea.Value,' ',''),',');
            
            if isempty(heldvars_each{1})
                heldvars_each = {};
            end
            if isempty(heldvars_all{1})
                heldvars_all = {};
            end
            
            var_specs.varied_var = varied_var;
            var_specs.heldvars_each = heldvars_each;
            var_specs.heldvars_all = heldvars_all;
            var_specs.condition = condition;
            
            ThisData = RunDataLibrary();
            ThisData = ThisData.libraryConstruct(app.InputData,condition);
            ThisRunData = ThisData.RunDatas;
            
            %%NOTE that in general assignin is something that we should try
            %%to avoid. However, in this case we want to make it REALLY
            %%easy for the end user, and just have the new rundata show up
            %%in their matlab workspace. A better approach is to use
            %%function outputs, but the workaround for appdesigner is a bit
            %%cumbersome...
            
%             assignin('base',app.nameEditField.Value,ThisRunData);
%             assignin('base',strcat(app.nameEditField.Value,'Library'),ThisData);
%             assignin("base",strcat(app.nameEditField.Value,'Variable_Specs'),var_specs);
            
            assignin('base','RunDatas',ThisRunData);
            assignin('base','RunDataLib',ThisData);
            assignin("base",'RunVars',var_specs);
            
            disp('RunDatas, RunDataLib, and RunVars saved to workspace.');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.dataTable = uitable(app.UIFigure);
            app.dataTable.ColumnName = {'Folder'; 'Date'; 'RunID (DataManager)'; 'Include?'};
            app.dataTable.RowName = {};
            app.dataTable.ColumnEditable = [false false false true];
            app.dataTable.CellSelectionCallback = createCallbackFcn(app, @dataTableCellSelection, true);
            app.SwitchAllHighlightedEntriesButton = uibutton(app.UIFigure, 'push');
            app.SwitchAllHighlightedEntriesButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchAllHighlightedEntriesButtonPushed, true);
            app.SwitchAllHighlightedEntriesButton.Text = {'Switch'; 'All  '; 'Highlighted'; 'Entries'};
            app.DataSelectionToolLabel = uilabel(app.UIFigure);
            app.DataSelectionToolLabel.Text = 'Data Selection Tool';
            app.ThistoolwillhelpyouLabel = uilabel(app.UIFigure);
            app.ThistoolwillhelpyouLabel.Text = {'This tool will help you automatically select a subset of runs from the RunDataLibrary that you generated using AutoLibraryGenerator.'; 'The idea is you would want to select a smaller subset of the above to plot/compare easily.'; '(once again make sure to have dataManager and subfolders added to path.)'};
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step 1: This tool should be run from the MATLAB, with the RunDataLibrary as an argument (i.e. >>runConditions(Data)) ';
            app.Step1Label_2 = uilabel(app.UIFigure);
            app.Step1Label_2.Text = 'Step 2: Choose the runs that you want to plot/analyze from the list below: ';
            app.generateButton = uibutton(app.UIFigure, 'push');
            app.generateButton.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.generateButton.Text = {'Step 4: '; 'Generate RunDatas (and Lib) '; 'and save to workspace !'};
            app.VariedVarsCSVspacesokLabel = uilabel(app.UIFigure);
            app.VariedVarsCSVspacesokLabel.Text = {'VariedVars'; '(CSV, spaces ok)'};
            app.VariedTextArea = uitextarea(app.UIFigure);
            app.VariedTextArea.Value = {'change_me, and_me'};
            app.Step1Label_3 = uilabel(app.UIFigure);
            app.Step1Label_3.Text = 'Step 3: List variables that are changing/constant (for plotting titles/labels) ';
            app.HeldVarsEachCSVspacesokTextAreaLabel = uilabel(app.UIFigure);
            app.HeldVarsEachCSVspacesokTextAreaLabel.Text = {'HeldVars Each'; '(CSV, spaces ok)'};
            app.HeldTextArea = uitextarea(app.UIFigure);
            app.HeldTextArea.Value = {'change_me, and_me'};
            app.HeldVarsAllCSVspacesokLabel = uilabel(app.UIFigure);
            app.HeldVarsAllCSVspacesokLabel.Text = {'HeldVars All'; '(CSV, spaces ok)'};
            app.HeldAllTextArea = uitextarea(app.UIFigure);
            app.HeldAllTextArea.Value = {'change_me, and_me'};
            app.Step4WhatwouldyoulikeyouratomDatastobecalledEditFieldLabel = uilabel(app.UIFigure);
            app.Step4WhatwouldyoulikeyouratomDatastobecalledEditFieldLabel.Visible = 'off';
            app.Step4WhatwouldyoulikeyouratomDatastobecalledEditFieldLabel.Text = {'Step 4: What would you like your'; 'runDatas to be called?'; '(be careful to not overwrite variables'; 'already in your workspace!)'};
            app.nameEditField = uieditfield(app.UIFigure, 'text');
            app.nameEditField.Visible = 'off';
            app.VariablevariedacrosseachselectedrunLabel = uilabel(app.UIFigure);
            app.VariablevariedacrosseachselectedrunLabel.Text = {'Variable varied across'; 'each selected run.'};
            app.VariablesheldconstantthroughouteachrunLabel = uilabel(app.UIFigure);
            app.VariablesheldconstantthroughouteachrunLabel.Text = {'Variables held constant'; 'throughout each run.'};
            app.VariablesheldconstantacrossallselectedrunsLabel = uilabel(app.UIFigure);
            app.VariablesheldconstantacrossallselectedrunsLabel.Text = {'Variables held constant'; 'across all selected runs.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectRuns(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end